classdef equipSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        Spinner          matlab.ui.control.Spinner
        Spinner_2Label   matlab.ui.control.Label
        Spinner_2        matlab.ui.control.Spinner
        DropDown         matlab.ui.control.DropDown
        DropDown_2       matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        Label_3          matlab.ui.control.Label
        DropDown_4       matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Label_5          matlab.ui.control.Label
        DropDown_5       matlab.ui.control.DropDown
        Label_6          matlab.ui.control.Label
        DropDown_6       matlab.ui.control.DropDown
        UPDropDownLabel  matlab.ui.control.Label
        UPDropDown       matlab.ui.control.DropDown
        Label_7          matlab.ui.control.Label
        DropDown_7       matlab.ui.control.DropDown
        Label_8          matlab.ui.control.Label
        DropDown_8       matlab.ui.control.DropDown
        Label_9          matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
        Label_10         matlab.ui.control.Label
        Slider_3         matlab.ui.control.Slider
        Label_11         matlab.ui.control.Label
        Slider_4         matlab.ui.control.Slider
        Label_12         matlab.ui.control.Label
        Slider_5         matlab.ui.control.Slider
        Label_13         matlab.ui.control.Label
        Slider_6         matlab.ui.control.Slider
        UITable          matlab.ui.control.Table
        Button           matlab.ui.control.Button
        UITable2         matlab.ui.control.Table
        UITable3         matlab.ui.control.Table
        UITable4         matlab.ui.control.Table
        UITable5         matlab.ui.control.Table
        UIAxes           matlab.ui.control.UIAxes
        Label_14         matlab.ui.control.Label
        Label_15         matlab.ui.control.Label
        Label_16         matlab.ui.control.Label
        Label_17         matlab.ui.control.Label
        Label_18         matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dammy
    end
    
    properties (Access = public)
        costCustomHealth=0 % Description
        weaponDeco=[1;1;1;1]; % Description
        elementLocation=8; % Description
        weaponAttack=270+57; % Description
        weaponElement=180 % Description
        weaponCritical=5 % Description
        physicalMonster=45 % Description
        Mvalue=95 % Description
        elementalMonster=10 % Description
        NumHit=5 % Description
        razorLength=40 % Description
        criticalBoost=3 % Description
        offensiveGuard=1; % Description
        elemCrit=1 % Description
        elemCritTrue=0 % Description
        realCritical=80 % Description
        initSharp=60 % Description
        Wazamono=0 % Description
        Tatsuzingei=0 % Description
        givenPoints=zeros(28,1) % Description
        givenFocus=0; % Description
        givenHealth=0; % Description
        givenUp=0 % Description
        givenGuardPow=0 % Description
        ratioOG=0 % Description
        ratioSaika=0.5 % Description
        ratioChallenger=1 % Description
        ratioFull=0 % Description
        ratioDragon=1 % Description
        givenGuardAbi=0 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            if app.DropDown.Value == '傷あり'
                app.physicalMonster = value + (100-value)/4;
            else
                app.physicalMonster = value;
            end
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            app.elementalMonster = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if app.DropDown.Value == '傷あり'
                app.physicalMonster = app.Spinner.Value + (100-app.Spinner.Value)/4;
            else
                app.physicalMonster = app.Spinner.Value;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value == '炎'
                app.elementLocation = 8;
            elseif value == '水'
                app.elementLocation = 9;
            elseif value == '氷'
                app.elementLocation = 10;
            elseif value == '雷'
                app.elementLocation = 11;
            else
                app.elementLocation = 12;
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value == "なし"
                app.costCustomHealth = 0;
            elseif value == "強化１"
                app.costCustomHealth = 3;
            elseif value == "強化２"
                app.costCustomHealth = 5;
            else
                app.costCustomHealth = 7;
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            app.Mvalue = 95;
            app.NumHit = 5;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.razorLength = floor(value);
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            app.givenFocus=str2num(value); %集中
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            app.givenHealth=str2num(value); %体力増強
        end

        % Value changed function: UPDropDown
        function UPDropDownValueChanged(app, event)
            value = app.UPDropDown.Value;
            app.givenUp=str2num(value); %装填数
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            app.givenGuardPow=str2num(value); %ガード強化
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.ratioOG = value;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            app.ratioSaika = value;
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            app.ratioChallenger = value;
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            app.ratioFull = value;
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            app.ratioDragon = value;
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            value = app.DropDown_8.Value;
            app.givenGuardAbi = str2num(value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            addpath('src');
            
            %[ExtractedSkills, ExtractedSeriesSkills, limitedPoints]...
            %    = load_skillsList();
            load('ability_data/skillMat.mat');
            NS = size(ExtractedSkills,1);
            NSS = size(ExtractedSeriesSkills,1);
            %[equipMat, NameCollection, SizeCollection]...
            %    = make_data([ExtractedSkills;ExtractedSeriesSkills]');
            load('data/equipData20191220.mat');
            [abilityMat, customMat, awakeMat] = make_AbilityCustomAwake(app);
            
            %initilization;
            caseIndexSet = [];
            %caseIndexSet{1} = [3;2;1;0]; %超会心
            caseIndexSet{1} = [3]; %超会心
            caseIndexSet{2} = (100:-5:95)'; %会心率下限
            caseIndexSet{3} = (40:10:160)'; %斬れ味初期位置
            caseIndexSet{4} = [0]; %offensive guard
            caseIndexSet{5} = [0;1]; %達人芸
            caseIndexSet{6} = [0;1]; %業物
            caseIndexSet{7} = [0;1]; %属性会心
            caseIndexSet{8} = [0;1]; %真属性会心
            
            best = 0;
            ob=[];


            for ii1=1:size(caseIndexSet{1},1)
                for ii2=1:size(caseIndexSet{2},1)
                    for ii3=1:size(caseIndexSet{3},1)
                        for ii4=1:size(caseIndexSet{4},1)
                            for ii5=1:size(caseIndexSet{5},1)
                                for ii6=1:size(caseIndexSet{6},1)
                                    for ii7=1:size(caseIndexSet{7},1)
                                        for ii8=1:size(caseIndexSet{8},1)
                                            app = prepPram(app, caseIndexSet, [ii1;ii2;ii3;ii4;ii5;ii6;ii7;ii8]);
                                            [sol,fval,NameCollection] = solve_prob(app,...
                equipMat, NameCollection, SizeCollection,...
                abilityMat, customMat, awakeMat,...
                ExtractedSkills, ExtractedSeriesSkills, limitedPoints);
            
            if size(sol)>0
                if fval > best
                    app = repTable(app, sol, NameCollection);
                    app = repRazor(app);
                    pause(0.2);
                    best = fval;
                end
                ob=[ob;[best fval]];
                plot(app.UIAxes,ob,'Linewidth',2);
                
                pause(0.1);
            end
            
            
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '物理肉質';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 45;
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = '属性肉質';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 10;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'傷あり', '傷なし'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '傷あり';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'炎', '水', '氷', '雷', '龍'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '炎';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '武器カスタム回復';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'なし', '強化１', '強化２', '強化３'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'なし';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '評価用コンボ';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'牽制ー溜め２連ー回転', '斧変形ー解放２ー高出力'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '牽制ー溜め２連ー回転';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '計測用攻撃ヒット回数合計';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [10 200];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 48;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '集中';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'0', '1', '2', '3'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '0';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '体力増強';
            app.DropDown_6 = uidropdown(app.UIFigure);
            app.DropDown_6.Items = {'0', '1', '2', '3'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = '0';
            app.UPDropDownLabel = uilabel(app.UIFigure);
            app.UPDropDownLabel.Text = '砲弾装填数UP';
            app.UPDropDown = uidropdown(app.UIFigure);
            app.UPDropDown.Items = {'0', '1'};
            app.UPDropDown.ValueChangedFcn = createCallbackFcn(app, @UPDropDownValueChanged, true);
            app.UPDropDown.Value = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'ガード強化';
            app.DropDown_7 = uidropdown(app.UIFigure);
            app.DropDown_7.Items = {'0', '1'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Value = '0';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'ガード性能';
            app.DropDown_8 = uidropdown(app.UIFigure);
            app.DropDown_8.Items = {'0', '1', '3', '5'};
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.Value = '0';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '攻めの守勢';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '災禍転福';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 0.5;
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '挑戦者';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 1;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'フルチャージ';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '龍脈覚醒';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 1];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'攻撃'; '見切り'; '弱点特効'; '超会心'; '属性'; '匠'; '業物'; '達人芸'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '実行';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'防具'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'カスタム強化'};
            app.UITable3.RowName = {};
            app.UITable4 = uitable(app.UIFigure);
            app.UITable4.ColumnName = {'覚醒能力'};
            app.UITable4.RowName = {};
            app.UITable5 = uitable(app.UIFigure);
            app.UITable5.ColumnName = {'装飾品'; '個数'};
            app.UITable5.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '目的関数')
            xlabel(app.UIAxes, '解候補')
            ylabel(app.UIAxes, 'コンボ期待値')
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '0';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '0';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '0';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '0';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equipSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end